classdef BasinsofAttraction < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        AnimationpausetimeinsecondsEditField  matlab.ui.control.NumericEditField
        AnimationpausetimeinsecondsEditFieldLabel  matlab.ui.control.Label
        GeneraterootcolorsButton     matlab.ui.control.Button
        UITable2                     matlab.ui.control.Table
        DegreeofpolynomialEditField  matlab.ui.control.NumericEditField
        DegreeofpolynomialEditFieldLabel  matlab.ui.control.Label
        PlotButton                   matlab.ui.control.StateButton
        gridonoffCheckBox            matlab.ui.control.CheckBox
        MaxIterationsEditField       matlab.ui.control.NumericEditField
        MaxIterationsEditFieldLabel  matlab.ui.control.Label
        MEditField                   matlab.ui.control.NumericEditField
        MEditFieldLabel              matlab.ui.control.Label
        ToleranceEditField           matlab.ui.control.NumericEditField
        ToleranceEditFieldLabel      matlab.ui.control.Label
        nEditField                   matlab.ui.control.NumericEditField
        nEditFieldLabel              matlab.ui.control.Label
        mEditField                   matlab.ui.control.NumericEditField
        mEditFieldLabel              matlab.ui.control.Label
        y_maxEditField               matlab.ui.control.NumericEditField
        y_maxEditFieldLabel          matlab.ui.control.Label
        y_minEditField               matlab.ui.control.NumericEditField
        y_minEditFieldLabel          matlab.ui.control.Label
        x_maxEditField               matlab.ui.control.NumericEditField
        x_maxEditFieldLabel          matlab.ui.control.Label
        x_minEditField               matlab.ui.control.NumericEditField
        x_minEditFieldLabel          matlab.ui.control.Label
        UITable                      matlab.ui.control.Table
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Plotting(1, 1) logical = false; % Boolean that indicates if app is currently plotting
        Cmap(:, 3) double = []; % Color map
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Initializes some UI elements

            % Set window title
            app.UIFigure.Name = "Basins of attraction";
            
            % Fill polynomial coefficients table
            app.DegreeofpolynomialEditFieldValueChanged();
            
            % Set UIAxes layer to top so grid is visible also after plotting
            app.UIAxes.Layer = 'top';
            
            % Set root tables horizontal alignment to left
            s = uistyle('HorizontalAlignment', 'left', "BackgroundColor", [1, 1, 1]);
            addStyle(app.UITable2, s);
            
        end

        % Value changed function: PlotButton
        function PlotButtonPushed(app, event)
            % Calculates and displays the colors for all points in the given range

            if ~app.Plotting
                
                cla(app.UIAxes) % Clear the axes

                app.Plotting = true;
                app.PlotButton.Text = 'Stop';

                % Retrieve plotting parameters from UI
                tol = app.ToleranceEditField.Value;
                M = app.MEditField.Value;
                maxiter = app.MaxIterationsEditField.Value;
                
                x_min = app.x_minEditField.Value;
                x_max = app.x_maxEditField.Value;
                y_min = app.y_minEditField.Value;
                y_max = app.y_maxEditField.Value;
                m = app.mEditField.Value;
                n = app.nEditField.Value;
                
                app.UIAxes.XLim = [x_min, x_max];
                app.UIAxes.YLim = [y_min, y_max];
                
                % Create Z matrix that contains all points to be processed
                x = linspace(x_min, x_max, m);
                y = linspace(y_min, y_max, n);
                [X, Y] = meshgrid(x, y);
                Z = X + 1i*Y;
            
                C = zeros(length(y), length(x), 'uint8');

                % Get polynomial coefficients
                p = app.UITable.Data;
                
                % Calculate derivative
                dp = polyder(p);
                % Calculate roots
                r = roots(p);
                numOfRoots = length(r);
    
    
                for j = 1:maxiter
                    
                    % Update figures and process callbacks between iterations
                    drawnow 
                    
                    if ~app.Plotting; return; end
                    
                    % Calulate next iteration
                    Z = Z - polyval(p, Z)./(polyval(dp, Z) + eps); % To avoid division by zero
                    
                    
                    % Set color values
                    for k = 1:numOfRoots
                        C(abs(Z - r(k)) < tol) = k;
                    end
                    C(abs(Z) > M) = 0;
        
                    % Plot colors
                    image(app.UIAxes, x, y, C);
                    colormap(app.UIAxes, app.Cmap);
                    
                    % Plot roots
                    hold(app.UIAxes, 'on')
                    plot(app.UIAxes, r, 'o', 'MarkerFaceColor', 'y')
                    hold(app.UIAxes, 'off')
                    
                    % Pause animation for given time
                    pause(app.AnimationpausetimeinsecondsEditField.Value);

                end

            end
            
            % Plotting stopped
            app.Plotting = false;
            app.PlotButton.Text = 'Plot';
            app.PlotButton.Value = false;
    
        end

        % Value changed function: gridonoffCheckBox
        function gridonoffCheckBoxValueChanged(app, event)
            % Toggles the grid

            if app.gridonoffCheckBox.Value
                grid(app.UIAxes, 'on')
            else
                grid(app.UIAxes, 'off')
            end
        end

        % Value changed function: DegreeofpolynomialEditField
        function DegreeofpolynomialEditFieldValueChanged(app, event)
            % Fills the polynomial coefficients table with correct amount of rows
            
            deg = app.DegreeofpolynomialEditField.Value;
            
            % Validate input
            if deg < 1 || mod(deg, 1) ~= 0
                errordlg('Input must be a integer >= 1','Error');
                app.DegreeofpolynomialEditField.Value = event.PreviousValue;
                return
            end

            % Fill polynomial coefficients table with zeros
            app.UITable.Data = zeros(deg + 1, 1);
            
            % Set polynomial coefficients table row names
            rowNames = cell(1, deg + 1);
            for i = 0:deg
                rowNames{i + 1} = strcat('x^', num2str(deg - i));
            end
            app.UITable.RowName = rowNames;
            
            % Clear roots table
            app.UITable2.Data = [];
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            % Recalculates the roots after coefficient has been modified
            
            indices = event.Indices;
            newData = event.NewData;
            
            % Check that new value is a number
            if isnan(newData) || isinf(newData)
                errordlg('Input must be a number','Error');
                app.UITable.Data(indices(1)) = 0;
            end

            % Get new polynomial coefficients
            p = app.UITable.Data;
            
            % Recalculate roots
            r = roots(p);
            
            % Update roots table
            app.UITable2.Data = r;
        
        end

        % Cell selection callback: UITable2
        function UITable2CellSelection(app, event)
            % Lets you select a new color for the root and updates the colormap

            if ~isempty(event.Indices)
                
                % Open color selections window
                c = uisetcolor;
                
                % If color selection window was closed without selcting
                % color set color to white
                if ~c; c = [1, 1, 1]; end
                
                % Set cell color
                s = uistyle("BackgroundColor", c);
                addStyle(app.UITable2, s, "cell", [event.Indices(1), event.Indices(2)])
                
                % Update colormap
                app.Cmap(event.Indices(1) + 1, :) = c;

                % Disable table cell selection so cell can be selected
                % again without selecting another cell in between
                app.UITable2.Selection = [];

            end
            
        end

        % Button pushed function: GeneraterootcolorsButton
        function GeneraterootcolorsButtonPushed(app, event)
            % Generate random colors for all roots and update colormap

            deg = app.DegreeofpolynomialEditField.Value;
            
            % Clear colormap
            app.Cmap = zeros(deg + 1, 3);
            app.Cmap(1, :) = [1, 1, 1];
            
            % Generate new random colors for colormap 
            % and update corresponding root table colors
            for i=2:deg+1
                c = [rand, rand, rand];
                app.Cmap(i, :) = c;
                s = uistyle("BackgroundColor", c);
                addStyle(app.UITable2, s, "cell", [i - 1, 1])
            end

        end

        % Value changed function: AnimationpausetimeinsecondsEditField
        function AnimationpausetimeinsecondsEditFieldValueChanged(app, event)
            % Validates AnimationpausetimeinsecondsEditFields input

            value = app.AnimationpausetimeinsecondsEditField.Value;
            if value < 0
                errordlg('Input must be a non negative number','Error');
                app.AnimationpausetimeinsecondsEditField.Value = event.PreviousValue;
            end
        end

        % Value changed function: MaxIterationsEditField
        function MaxIterationsEditFieldValueChanged(app, event)
            % Validates MaxIterationsEditFields input

            value = app.MaxIterationsEditField.Value;
            if value < 1 || mod(value, 1) ~= 0
                errordlg('Input must be a integer >= 1','Error');
                app.MaxIterationsEditField.Value = event.PreviousValue;
            end
        end

        % Value changed function: x_minEditField
        function x_minEditFieldValueChanged(app, event)
            % Validates x_minEditFields input
            
            value = app.x_minEditField.Value;
            if isinf(value)
                errordlg('x_min cant be Inf','Error');
                app.x_minEditField.Value = event.PreviousValue;
            end
            if value >= app.x_maxEditField.Value
                errordlg('x_min cant be more than x_max','Error');
                app.x_minEditField.Value = event.PreviousValue;
            end
        end

        % Value changed function: x_maxEditField
        function x_maxEditFieldValueChanged(app, event)
            % Validates x_maxEditFields input

            value = app.x_maxEditField.Value;
            if isinf(value)
                errordlg('x_max cant be Inf','Error');
                app.x_maxEditField.Value = event.PreviousValue;
            end
            if value <= app.x_minEditField.Value
                errordlg('x_max cant be less than x_min','Error');
                app.x_maxEditField.Value = event.PreviousValue;
            end
        end

        % Value changed function: y_minEditField
        function y_minEditFieldValueChanged(app, event)
            % Validates y_minEditFields input

            value = app.y_minEditField.Value;
            if isinf(value)
                errordlg('y_min cant be Inf','Error');
                app.y_minEditField.Value = event.PreviousValue;
            end
            if value >= app.y_maxEditField.Value
                errordlg('y_min cant be more than y_max','Error');
                app.y_minEditField.Value = event.PreviousValue;
            end
        end

        % Value changed function: y_maxEditField
        function y_maxEditFieldValueChanged(app, event)
            % Validates y_maxEditFields input
            
            value = app.y_maxEditField.Value;
            if isinf(value)
                errordlg('y_max cant be Inf','Error');
                app.y_maxEditField.Value = event.PreviousValue;
            end
            if value <= app.y_minEditField.Value
                errordlg('y_max cant be less than y_min','Error');
                app.y_maxEditField.Value = event.PreviousValue;
            end
        end

        % Value changed function: mEditField
        function mEditFieldValueChanged(app, event)
            % Validates mEditFields input

            value = app.mEditField.Value;
            if value < 1 || mod(value, 1) ~= 0
                errordlg('Input must be a integer >= 1','Error');
                app.mEditField.Value = event.PreviousValue;
            end
        end

        % Value changed function: nEditField
        function nEditFieldValueChanged(app, event)
            % Validates nEditFields input

            value = app.nEditField.Value;
            if value < 1 || mod(value, 1) ~= 0
                errordlg('Input must be a integer >= 1','Error');
                app.nEditField.Value = event.PreviousValue;
            end
        end

        % Value changed function: ToleranceEditField
        function ToleranceEditFieldValueChanged(app, event)
            % Validates ToleranceEditFields input

            value = app.ToleranceEditField.Value;
            if value < 0
                errordlg('Input must be >= 0','Error');
                app.ToleranceEditField.Value = event.PreviousValue;
            end
        end

        % Value changed function: MEditField
        function MEditFieldValueChanged(app, event)
            % Validates MEditFields input

            value = app.MEditField.Value;
            if value <= 0
                errordlg('Input must be > 0','Error');
                app.MEditField.Value = event.PreviousValue;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Re')
            ylabel(app.UIAxes, 'Im')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'coefficients'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [true true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.Multiselect = 'off';
            app.x_minEditFieldLabel = uilabel(app.UIFigure);
            app.x_minEditFieldLabel.Text = 'x_min';
            app.x_minEditField = uieditfield(app.UIFigure, 'numeric');
            app.x_minEditField.ValueChangedFcn = createCallbackFcn(app, @x_minEditFieldValueChanged, true);
            app.x_minEditField.Value = -1.2;
            app.x_maxEditFieldLabel = uilabel(app.UIFigure);
            app.x_maxEditFieldLabel.Text = 'x_max';
            app.x_maxEditField = uieditfield(app.UIFigure, 'numeric');
            app.x_maxEditField.ValueChangedFcn = createCallbackFcn(app, @x_maxEditFieldValueChanged, true);
            app.x_maxEditField.Value = 1.2;
            app.y_minEditFieldLabel = uilabel(app.UIFigure);
            app.y_minEditFieldLabel.Text = 'y_min';
            app.y_minEditField = uieditfield(app.UIFigure, 'numeric');
            app.y_minEditField.ValueChangedFcn = createCallbackFcn(app, @y_minEditFieldValueChanged, true);
            app.y_minEditField.Value = -1.2;
            app.y_maxEditFieldLabel = uilabel(app.UIFigure);
            app.y_maxEditFieldLabel.Text = 'y_max';
            app.y_maxEditField = uieditfield(app.UIFigure, 'numeric');
            app.y_maxEditField.ValueChangedFcn = createCallbackFcn(app, @y_maxEditFieldValueChanged, true);
            app.y_maxEditField.Value = 1.2;
            app.mEditFieldLabel = uilabel(app.UIFigure);
            app.mEditFieldLabel.Text = 'm';
            app.mEditField = uieditfield(app.UIFigure, 'numeric');
            app.mEditField.ValueChangedFcn = createCallbackFcn(app, @mEditFieldValueChanged, true);
            app.mEditField.Value = 1000;
            app.nEditFieldLabel = uilabel(app.UIFigure);
            app.nEditFieldLabel.Text = 'n';
            app.nEditField = uieditfield(app.UIFigure, 'numeric');
            app.nEditField.ValueChangedFcn = createCallbackFcn(app, @nEditFieldValueChanged, true);
            app.nEditField.Value = 1000;
            app.ToleranceEditFieldLabel = uilabel(app.UIFigure);
            app.ToleranceEditFieldLabel.Text = 'Tolerance';
            app.ToleranceEditField = uieditfield(app.UIFigure, 'numeric');
            app.ToleranceEditField.ValueChangedFcn = createCallbackFcn(app, @ToleranceEditFieldValueChanged, true);
            app.ToleranceEditField.Value = 0.1;
            app.MEditFieldLabel = uilabel(app.UIFigure);
            app.MEditFieldLabel.Text = 'M';
            app.MEditField = uieditfield(app.UIFigure, 'numeric');
            app.MEditField.ValueChangedFcn = createCallbackFcn(app, @MEditFieldValueChanged, true);
            app.MEditField.Value = 1000;
            app.MaxIterationsEditFieldLabel = uilabel(app.UIFigure);
            app.MaxIterationsEditFieldLabel.Text = 'Max Iterations';
            app.MaxIterationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxIterationsEditField.ValueChangedFcn = createCallbackFcn(app, @MaxIterationsEditFieldValueChanged, true);
            app.MaxIterationsEditField.Value = 50;
            app.gridonoffCheckBox = uicheckbox(app.UIFigure);
            app.gridonoffCheckBox.ValueChangedFcn = createCallbackFcn(app, @gridonoffCheckBoxValueChanged, true);
            app.gridonoffCheckBox.Text = ' grid on/off';
            app.PlotButton = uibutton(app.UIFigure, 'state');
            app.PlotButton.ValueChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.DegreeofpolynomialEditFieldLabel = uilabel(app.UIFigure);
            app.DegreeofpolynomialEditFieldLabel.Text = 'Degree of polynomial';
            app.DegreeofpolynomialEditField = uieditfield(app.UIFigure, 'numeric');
            app.DegreeofpolynomialEditField.ValueChangedFcn = createCallbackFcn(app, @DegreeofpolynomialEditFieldValueChanged, true);
            app.DegreeofpolynomialEditField.Value = 3;
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'Select Root Colors'};
            app.UITable2.RowName = {};
            app.UITable2.CellSelectionCallback = createCallbackFcn(app, @UITable2CellSelection, true);
            app.UITable2.Multiselect = 'off';
            app.GeneraterootcolorsButton = uibutton(app.UIFigure, 'push');
            app.GeneraterootcolorsButton.ButtonPushedFcn = createCallbackFcn(app, @GeneraterootcolorsButtonPushed, true);
            app.GeneraterootcolorsButton.Text = 'Generate root colors';
            app.AnimationpausetimeinsecondsEditFieldLabel = uilabel(app.UIFigure);
            app.AnimationpausetimeinsecondsEditFieldLabel.Text = 'Animation pause time in seconds';
            app.AnimationpausetimeinsecondsEditField = uieditfield(app.UIFigure, 'numeric');
            app.AnimationpausetimeinsecondsEditField.ValueChangedFcn = createCallbackFcn(app, @AnimationpausetimeinsecondsEditFieldValueChanged, true);
            app.AnimationpausetimeinsecondsEditField.Value = 0.02;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BasinsofAttraction

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end